classdef LineBreakerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LineBreakerAppUIFigure          matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        CloseMenu                       matlab.ui.container.Menu
        SaveLinesMenu                   matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        ExpandedLigaturesTab            matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        ExpandeddiacriticsTab           matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        LinesofTextTab                  matlab.ui.container.Tab
        UIAxes3                         matlab.ui.control.UIAxes
        BinarizedImageTab               matlab.ui.container.Tab
        UIAxes4                         matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        ImageFileSettingsPanel          matlab.ui.container.Panel
        EmptyFilePathLabel              matlab.ui.control.Label
        DetectResolutionButton          matlab.ui.control.Button
        DotsPerPointEditFieldLabel      matlab.ui.control.Label
        DotsPerPointEditField           matlab.ui.control.NumericEditField
        BinarizationSettingsPanel       matlab.ui.container.Panel
        BinarizationTypeListBoxLabel    matlab.ui.control.Label
        BinarizationTypeListBox         matlab.ui.control.ListBox
        ThresholdEditFieldLabel         matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.NumericEditField
        SegmentationSettingsPanel       matlab.ui.container.Panel
        BigSizeThresholdEditFieldLabel  matlab.ui.control.Label
        BigSizeThresholdEditField       matlab.ui.control.NumericEditField
        SmallSizeThresholdEditFieldLabel  matlab.ui.control.Label
        SmallSizeThresholdEditField     matlab.ui.control.NumericEditField
        VerticalExpansionEditFieldLabel  matlab.ui.control.Label
        VerticalExpansionEditField      matlab.ui.control.NumericEditField
        HoriontalExpansionEditFieldLabel  matlab.ui.control.Label
        HoriontalExpansionEditField     matlab.ui.control.NumericEditField
        OutputSettingsPanel             matlab.ui.container.Panel
        OutputTypeListBoxLabel          matlab.ui.control.Label
        OutputTypeListBox               matlab.ui.control.ListBox
        SingleFileActionsPanel          matlab.ui.container.Panel
        BinarizeImageButton             matlab.ui.control.Button
        FindLinesButton                 matlab.ui.control.Button
        BatchProcessingPanel            matlab.ui.container.Panel
        FilePatternEditFieldLabel       matlab.ui.control.Label
        FilePatternEditField            matlab.ui.control.EditField
        OutputFolderEditFieldLabel      matlab.ui.control.Label
        OutputFolderEditField           matlab.ui.control.EditField
        RunButton                       matlab.ui.control.Button
        BatchProgressInfoLabel          matlab.ui.control.Label
        InterruptButton                 matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        ob % Associated LineBreaker
    end
    
    properties (Access=private)
        a % XLim
        b % YLim
    end
    
    methods (Access = private)
        
        function clearAxes(app)
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            cla(app.UIAxes4)
            drawnow limitrate nocallbacks;
        end
    end
    
    methods (Access = public)
        
   
    end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            app.ob = LineBreaker(app);
            app.a = app.UIAxes.XLim;
            app.b = app.UIAxes.YLim;
            app.DotsPerPointEditField.Value = app.ob.DotsPerPoint;
            app.BigSizeThresholdEditField.Value = app.ob.BigSizeThreshold;
            app.SmallSizeThresholdEditField.Value = app.ob.SmallSizeThreshold;
            app.VerticalExpansionEditField.Value = app.ob.RelVertExpansion;
            app.HoriontalExpansionEditField.Value = app.ob.RelHorExpansion;
            app.ThresholdEditField.Value = app.ob.BinarizationThresh;
            app.OutputTypeListBox.Value = app.ob.OutputType;
            app.FilePatternEditField.Value = app.ob.InputFilePatternCSV;
            app.OutputFolderEditField.Value = app.ob.OutputDirectory;
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            app.ob.openFile(event);
            app.EmptyFilePathLabel.Text = app.ob.CurrentFilepath;
            app.DotsPerPointEditField.Value = app.ob.DotsPerPoint;
            app.clearAxes;
        end

        % Button down function: LineBreakerAppUIFigure
        function LineBreakerAppUIFigureButtonDown(app, event)
            if any(app.UIAxes.XLim ~= app.a) || any(app.UIAxes.YLim ~= app.b)
                app.UIAxes2.XLim = app.UIAxes.XLim;
                app.UIAxes2.YLim = app.UIAxes.YLim;
                app.UIAxes3.XLim = app.UIAxes.XLim;
            elseif any(app.UIAxes2.XLim ~= app.a) || any(app.UIAxes2.YLim ~= app.b)
                app.UIAxes.XLim = app.UIAxes2.XLim;
                app.UIAxes.YLim = app.UIAxes2.YLim;
                app.UIAxes3.XLim = app.UIAxes2.XLim;
                app.UIAxes3.YLim = app.UIAxes2.YLim;
            elseif any(App.UIAxes3.XLim ~= app.a) || any(app.UIAxes3.YLim ~= app.b)
                app.UIAxes.XLim = app.UIAxes3.XLim;
                app.UIAxesYLim = app.UIAxes3.YLim;
                app.UIAxes2.XLim = app.UIAxes3.XLim;
                app.UIAxes2.YLim = app.UIAxes3.YLim;
            end
            app.a = app.UIAxes.XLim;
            app.b = app.UIAxes.YLim;
            drawnow limitrate nocallbacks;
        end

        % Menu selected function: SaveLinesMenu
        function SaveLinesMenuSelected(app, event)
            app.ob.saveLines(event);
        end

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
            app.ob.closeFile(event);
            app.clearAxes;
        end

        % Value changed function: BigSizeThresholdEditField
        function BigSizeThresholdEditFieldValueChanged(app, event)
            value = app.BigSizeThresholdEditField.Value;
            app.ob.BigSizeThreshold = value;
        end

        % Value changed function: BinarizationTypeListBox
        function BinarizationTypeListBoxValueChanged(app, event)
            value = app.BinarizationTypeListBox.Value;
            app.ob.BinarizationType = value;
        end

        % Value changed function: DotsPerPointEditField
        function DotsPerPointEditFieldValueChanged(app, event)
            value = app.DotsPerPointEditField.Value;
            app.ob.DotsPerPoint = value;
        end

        % Button pushed function: FindLinesButton
        function FindLinesButtonPushed(app, event)
            if ~isempty(app.ob.CurrentFilepath)
                app.clearAxes;
                app.ob.locateText;
            else
                uialert(app.LineBreakerAppUIFigure,'File is not open. Please do!','File Not Opened')
            end
        end

        % Value changed function: HoriontalExpansionEditField
        function HoriontalExpansionEditFieldValueChanged(app, event)
            value = app.HoriontalExpansionEditField.Value;
            app.ob.RelHorExpansion = value;
        end

        % Value changed function: VerticalExpansionEditField
        function VerticalExpansionEditFieldValueChanged(app, event)
            value = app.VerticalExpansionEditField.Value;
            app.ob.RelBoxExpansion(1) = value;
        end

        % Button pushed function: DetectResolutionButton
        function DetectResolutionButtonPushed(app, event)
            app.ob.guessResolution;
            app.DotsPerPointEditField.Value = app.ob.DotsPerPoint;
        end

        % Button pushed function: BinarizeImageButton
        function BinarizeImageButtonPushed(app, event)
            cla(app.UIAxes4);
            drawnow limitrate nocallbacks;
            app.ob.binarizeCurrentImage;
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            value = app.ThresholdEditField.Value;
            app.ob.BinarizationThresh = value;
        end

        % Value changed function: OutputTypeListBox
        function OutputTypeListBoxValueChanged(app, event)
            value = app.OutputTypeListBox.Value;
            app.ob.OutputType = value;
        end

        % Value changed function: FilePatternEditField
        function FilePatternEditFieldValueChanged(app, event)
            value = app.FilePatternEditField.Value;
            app.ob.InputFilePattern = value;
        end

        % Value changed function: OutputFolderEditField
        function OutputFolderEditFieldValueChanged(app, event)
            value = app.OutputFolderEditField.Value;
            app.ob.OutputDirectory = value;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.ob.batch;
        end

        % Button pushed function: InterruptButton
        function InterruptButtonPushed(app, event)
            app.ob.InterruptFlag = true;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.LineBreakerAppUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {1030, 1030};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {937, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LineBreakerAppUIFigure = uifigure('Visible', 'off');
            app.LineBreakerAppUIFigure.Name = 'Line Breaker App';
            app.LineBreakerAppUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.LineBreakerAppUIFigure.ButtonDownFcn = createCallbackFcn(app, @LineBreakerAppUIFigureButtonDown, true);
            app.FileMenu = uimenu(app.LineBreakerAppUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Text = 'Close';
            app.SaveLinesMenu = uimenu(app.FileMenu);
            app.SaveLinesMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveLinesMenuSelected, true);
            app.SaveLinesMenu.Text = 'Save Lines';
            app.HelpMenu = uimenu(app.LineBreakerAppUIFigure);
            app.HelpMenu.Text = 'Help';
            app.GridLayout = uigridlayout(app.LineBreakerAppUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.ExpandedLigaturesTab = uitab(app.TabGroup);
            app.ExpandedLigaturesTab.Title = 'Expanded Ligatures';
            app.UIAxes = uiaxes(app.ExpandedLigaturesTab);
            title(app.UIAxes, 'Expanded Ligatures')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ExpandeddiacriticsTab = uitab(app.TabGroup);
            app.ExpandeddiacriticsTab.Title = 'Expanded diacritics';
            app.UIAxes2 = uiaxes(app.ExpandeddiacriticsTab);
            title(app.UIAxes2, 'Expanded Diacritics')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.LinesofTextTab = uitab(app.TabGroup);
            app.LinesofTextTab.Title = 'Lines of Text';
            app.UIAxes3 = uiaxes(app.LinesofTextTab);
            title(app.UIAxes3, 'Lines of Text')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.BinarizedImageTab = uitab(app.TabGroup);
            app.BinarizedImageTab.Title = 'Binarized Image';
            app.UIAxes4 = uiaxes(app.BinarizedImageTab);
            title(app.UIAxes4, 'Binarized Image')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = 'Settings Panel';
            app.ImageFileSettingsPanel = uipanel(app.RightPanel);
            app.ImageFileSettingsPanel.Title = 'Image File Settings';
            app.EmptyFilePathLabel = uilabel(app.ImageFileSettingsPanel);
            app.EmptyFilePathLabel.Text = '(Empty File Path)';
            app.DetectResolutionButton = uibutton(app.ImageFileSettingsPanel, 'push');
            app.DetectResolutionButton.ButtonPushedFcn = createCallbackFcn(app, @DetectResolutionButtonPushed, true);
            app.DetectResolutionButton.Text = 'Detect Resolution';
            app.DotsPerPointEditFieldLabel = uilabel(app.ImageFileSettingsPanel);
            app.DotsPerPointEditFieldLabel.Text = 'Dots Per Point';
            app.DotsPerPointEditField = uieditfield(app.ImageFileSettingsPanel, 'numeric');
            app.DotsPerPointEditField.ValueChangedFcn = createCallbackFcn(app, @DotsPerPointEditFieldValueChanged, true);
            app.BinarizationSettingsPanel = uipanel(app.RightPanel);
            app.BinarizationSettingsPanel.Title = 'Binarization Settings';
            app.BinarizationTypeListBoxLabel = uilabel(app.BinarizationSettingsPanel);
            app.BinarizationTypeListBoxLabel.Text = 'Binarization Type';
            app.BinarizationTypeListBox = uilistbox(app.BinarizationSettingsPanel);
            app.BinarizationTypeListBox.Items = {'Fixed Threshold', 'Global', 'Adaptive Filter / Mean Method', 'Adaptive Filter / Gaussia Method', 'Adaptive Filter / Median Method'};
            app.BinarizationTypeListBox.ItemsData = {'FixedThreshold', 'Global', 'AdaptiveMean', 'AdaptiveGaussian', 'AdaptiveMedian', ''};
            app.BinarizationTypeListBox.ValueChangedFcn = createCallbackFcn(app, @BinarizationTypeListBoxValueChanged, true);
            app.BinarizationTypeListBox.Value = 'FixedThreshold';
            app.ThresholdEditFieldLabel = uilabel(app.BinarizationSettingsPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold ';
            app.ThresholdEditField = uieditfield(app.BinarizationSettingsPanel, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.SegmentationSettingsPanel = uipanel(app.RightPanel);
            app.SegmentationSettingsPanel.Title = 'Segmentation Settings';
            app.BigSizeThresholdEditFieldLabel = uilabel(app.SegmentationSettingsPanel);
            app.BigSizeThresholdEditFieldLabel.Text = 'Big Size Threshold';
            app.BigSizeThresholdEditField = uieditfield(app.SegmentationSettingsPanel, 'numeric');
            app.BigSizeThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @BigSizeThresholdEditFieldValueChanged, true);
            app.SmallSizeThresholdEditFieldLabel = uilabel(app.SegmentationSettingsPanel);
            app.SmallSizeThresholdEditFieldLabel.Text = 'Small Size Threshold';
            app.SmallSizeThresholdEditField = uieditfield(app.SegmentationSettingsPanel, 'numeric');
            app.VerticalExpansionEditFieldLabel = uilabel(app.SegmentationSettingsPanel);
            app.VerticalExpansionEditFieldLabel.Text = 'Vertical Expansion';
            app.VerticalExpansionEditField = uieditfield(app.SegmentationSettingsPanel, 'numeric');
            app.VerticalExpansionEditField.ValueChangedFcn = createCallbackFcn(app, @VerticalExpansionEditFieldValueChanged, true);
            app.HoriontalExpansionEditFieldLabel = uilabel(app.SegmentationSettingsPanel);
            app.HoriontalExpansionEditFieldLabel.Text = 'Horiontal Expansion';
            app.HoriontalExpansionEditField = uieditfield(app.SegmentationSettingsPanel, 'numeric');
            app.HoriontalExpansionEditField.ValueChangedFcn = createCallbackFcn(app, @HoriontalExpansionEditFieldValueChanged, true);
            app.OutputSettingsPanel = uipanel(app.RightPanel);
            app.OutputSettingsPanel.Title = 'Output Settings';
            app.OutputTypeListBoxLabel = uilabel(app.OutputSettingsPanel);
            app.OutputTypeListBoxLabel.Text = 'Output Type';
            app.OutputTypeListBox = uilistbox(app.OutputSettingsPanel);
            app.OutputTypeListBox.Items = {'Same As Input', 'Binarized'};
            app.OutputTypeListBox.ItemsData = {'Input', 'Binarized', ''};
            app.OutputTypeListBox.ValueChangedFcn = createCallbackFcn(app, @OutputTypeListBoxValueChanged, true);
            app.OutputTypeListBox.Value = 'Input';
            app.SingleFileActionsPanel = uipanel(app.RightPanel);
            app.SingleFileActionsPanel.Title = 'Single File Actions';
            app.BinarizeImageButton = uibutton(app.SingleFileActionsPanel, 'push');
            app.BinarizeImageButton.ButtonPushedFcn = createCallbackFcn(app, @BinarizeImageButtonPushed, true);
            app.BinarizeImageButton.Text = 'Binarize Image';
            app.FindLinesButton = uibutton(app.SingleFileActionsPanel, 'push');
            app.FindLinesButton.ButtonPushedFcn = createCallbackFcn(app, @FindLinesButtonPushed, true);
            app.FindLinesButton.Text = 'Find Lines';
            app.BatchProcessingPanel = uipanel(app.RightPanel);
            app.BatchProcessingPanel.Title = 'Batch Processing';
            app.FilePatternEditFieldLabel = uilabel(app.BatchProcessingPanel);
            app.FilePatternEditFieldLabel.Text = 'File Pattern';
            app.FilePatternEditField = uieditfield(app.BatchProcessingPanel, 'text');
            app.FilePatternEditField.ValueChangedFcn = createCallbackFcn(app, @FilePatternEditFieldValueChanged, true);
            app.FilePatternEditField.Value = '../images/*.png';
            app.OutputFolderEditFieldLabel = uilabel(app.BatchProcessingPanel);
            app.OutputFolderEditFieldLabel.Text = 'Output Folder';
            app.OutputFolderEditField = uieditfield(app.BatchProcessingPanel, 'text');
            app.OutputFolderEditField.ValueChangedFcn = createCallbackFcn(app, @OutputFolderEditFieldValueChanged, true);
            app.OutputFolderEditField.Value = '/tmp/LineBreakerOut';
            app.RunButton = uibutton(app.BatchProcessingPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.BatchProgressInfoLabel = uilabel(app.BatchProcessingPanel);
            app.BatchProgressInfoLabel.Text = 'BatchProgressInfo';
            app.InterruptButton = uibutton(app.BatchProcessingPanel, 'push');
            app.InterruptButton.ButtonPushedFcn = createCallbackFcn(app, @InterruptButtonPushed, true);
            app.InterruptButton.Text = 'Interrupt';
            % Show the figure after all components are created
            app.LineBreakerAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LineBreakerApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LineBreakerAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LineBreakerAppUIFigure)
        end
    end
end